classdef clustering_gui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        clustering_gui            matlab.ui.Figure
        load_more_data            matlab.ui.container.Menu
        dCBB                      matlab.ui.container.Menu
        dYY                       matlab.ui.container.Menu
        dGap                      matlab.ui.container.Menu
        dMixed                    matlab.ui.container.Menu
        GeneratedatasetMenu       matlab.ui.container.Menu
        dBrodinova                matlab.ui.container.Menu
        panel_clustering          matlab.ui.container.Panel
        text_norm                 matlab.ui.control.Label
        init_norm                 matlab.ui.control.DropDown
        text_centers              matlab.ui.control.Label
        init_centers              matlab.ui.control.DropDown
        text_clustering           matlab.ui.control.Label
        init_clustering           matlab.ui.control.DropDown
        uipanel2                  matlab.ui.container.Panel
        text_step_s               matlab.ui.control.Label
        test_end_s                matlab.ui.control.Label
        text_start_s              matlab.ui.control.Label
        text_start_k              matlab.ui.control.Label
        text_end_k                matlab.ui.control.Label
        text_step_k               matlab.ui.control.Label
        s_end                     matlab.ui.control.DropDown
        s_start                   matlab.ui.control.DropDown
        k_start                   matlab.ui.control.DropDown
        k_end                     matlab.ui.control.DropDown
        k_step                    matlab.ui.control.DropDown
        s_step                    matlab.ui.control.DropDown
        run_clustering            matlab.ui.control.Button
        dataset_feats             matlab.ui.control.Table
        save_clustering           matlab.ui.control.Button
        panel_data                matlab.ui.container.Panel
        plotter                   matlab.ui.control.UIAxes
        plotDim1                  matlab.ui.control.DropDown
        plotDim2                  matlab.ui.control.DropDown
        plotDim3                  matlab.ui.control.DropDown
        generate_new_figure       matlab.ui.control.CheckBox
        button_load               matlab.ui.control.Button
        dataset_norm              matlab.ui.control.DropDown
        features_values           matlab.ui.control.DropDown
        hold_features_properties  matlab.ui.control.CheckBox
        text_generate_figure      matlab.ui.control.Label
        text_normalize_data       matlab.ui.control.Label
        text_dimensions           matlab.ui.control.Label
        text_feature_values       matlab.ui.control.Label
        features_values_plot      matlab.ui.control.DropDown
        TextArea                  matlab.ui.control.TextArea
        panel_results             matlab.ui.container.Panel
        load_clustering           matlab.ui.control.Button
        results_pop               matlab.ui.control.DropDown
    end

    
    methods (Access = public)
        function activate_s(app)
            %Activate/de-activate the sparsity parameter controls
            switch app.init_clustering.Value
                case 'Sparse K-Means'
                    set(app.s_start,'enable','on');
                    set(app.s_end,'enable','on');
                    set(app.s_step,'enable','on');
                otherwise
                    set(app.s_start,'enable','off');
                    set(app.s_end,'enable','off');
                    set(app.s_step,'enable','off');
            end
            set(app.TextArea,'Enable','off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function clustering_gui_OpeningFcn(app)
            addpath(genpath(pwd)); %add everything to the MATLAB path
            gui_opening(app);      %initialize gui
        end

        % Menu selected function: dBrodinova
        function dBrodinova_Callback(app, event)
            set(app.clustering_gui,'Visible','off');
            brodinova_gui;
            set(app.clustering_gui,'Visible','on');
        end

        % Callback function: button_load, dCBB, dGap, dMixed, dYY
        function button_load_Callback(app, event)
            %Load the dataset
            set(app.clustering_gui,'Visible','off');
            [x,y,lb1,lb_out,str] = load_dataset(event.Source.Text);
            %Update the GUI
            set(app.clustering_gui,'Visible','on');
            if isempty(x)
                % Leave everything as they are with the previous dataset, if any
                return
            else
                set(app.button_load,'UserData',{x,y,lb1,lb_out,str});
                set(app.save_clustering,'Enable','off');
                set(app.run_clustering,'UserData','');
                gui_update(app);
                activate_s(app);
                plotter_exe(app, event); %plot the dataset
            end
        end

        % Value changed function: dataset_norm, 
        % generate_new_figure, plotDim1, plotDim2, plotDim3
        function plotter_exe(app, event)
            data = get(app.button_load,'UserData');
            plot_dataset(data{1},data{2},app.plotter,app.plotDim1,app.plotDim2,app.plotDim3,app.generate_new_figure,app.dataset_norm);
        end

        % Value changed function: features_values
        function features_values_Callback(app, event)
            p = str2double(get(app.features_values,'Value'));
            %pl = get(app.features_values_plot,'Value'); %only boxplot for now...
            x_labs = get(app.button_load,'UserData');
            x = x_labs{1};
            x_labs = x_labs{2};
            c = unique(x_labs);
            x = normalizations(x,get(app.dataset_norm,'Value'));
            tickxl = [num2cell(c);'all'];
            color_box = repmat({'white'},1,length(c));
            color_box = [{'black'},color_box];
            color_median = repmat({'black'},1,length(c));
            color_median = [{'white'},color_median];
            t = sprintf('Feature %d',p);
            % Features values per class
            stratsFeats = cell(1,length(c)+1);
            for j = 1:length(c)
                stratsFeats{1,j} = x(x_labs==c(j),p);
            end
            stratsFeats{1,length(c)+1} = x(:,p);
            % Make the plot
            NewF = findobj('type','figure','tag','NewF');
            if exist('NewF','var') && ~get(app.hold_features_properties,'Value')
                close(NewF);
            end
            NewF = customBoxplot(stratsFeats,'maximize_figure',0,'Visible','off',...
                  'Xlabel','classes / clusters','Ylabel','feature values','Title',t,'tickxl',tickxl,...
                  'color_box',color_box,'color_median',color_median);
            set(NewF,'Visible','on');
            set(app.TextArea,'Enable','off');
        end

        % Value changed function: init_clustering
        function init_clustering_Callback(app, event)
            activate_s(app)
        end

        % Button pushed function: load_clustering
        function load_clustering_Callback(app, event)
            % Load clustering results from this GUI
            err = load_CLRES(app);
            if err == 1
                return
            end
            activate_s(app);
        end

        % Value changed function: results_pop
        function results_pop_Callback(app, event)
            gui_results(app, event);
        end

        % Button pushed function: run_clustering
        function run_clustering_Callback(app, event)
            % Get user options
            [method_norm,method_centers,method_cluster,x,KS,SS,iflag] = collect_options(app);
            if iflag
                myGUI = findobj(app.clustering_gui,'Enable','off');
                set(myGUI,'Enable','on');
                activate_s(app);
                set(app.results_pop,'Enable','off');
                return
            end
            % Disable the GUI
            myGUI = findobj(app.clustering_gui,'Enable','on');
            set(myGUI,'Enable','off');
            pause(0.005)
            % Execute clustering
            [CL_RESULTS,DATA,PARAMS,EXTRAS] = clustering_exe(method_norm,method_centers,method_cluster,x,KS,SS);
%             if ~isempty(find(arrayfun(@(x) isempty(x.w), CL_RESULTS)==1))
%                 % Reset; the clustering was not complete
%                 myGUI = findobj(app.clustering_gui,'Enable','off');
%                 set(myGUI,'Enable','on');
%                 activate_s(app);
%                 set(handles.save_clustering,'Enable','off');
%                 set(app.results_pop,'Enable','off');
%                 return
%             end
            fdata = get(app.dataset_feats,'Data');
            PARAMS.UI = {fdata,method_norm,method_centers,method_cluster};
            ORIGINAL_DATA = get(app.button_load,'UserData');
            set(app.run_clustering,'UserData',{CL_RESULTS,DATA,PARAMS,EXTRAS,ORIGINAL_DATA});
            % Re-activate GUI
            myGUI = findobj(app.clustering_gui,'Enable','off');
            set(myGUI,'Enable','on');
            if size(ORIGINAL_DATA{1},2) == 1
                set(app.plotDim2,'enable','off');
                set(app.plotDim3,'enable','off');
            elseif size(ORIGINAL_DATA{1},2) == 2
                set(app.plotDim3,'enable','off');
            end
            activate_s(app)
        end

        % Button pushed function: save_clustering
        function save_clustering_Callback(app, event)
            save_all(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.clustering_gui = uifigure('Visible', 'off');
            app.clustering_gui.Name = 'Clustering GUI';
            app.load_more_data = uimenu(app.clustering_gui);
            app.load_more_data.Text = 'Load dataset';
            app.dCBB = uimenu(app.load_more_data);
            app.dCBB.MenuSelectedFcn = createCallbackFcn(app, @button_load_Callback, true);
            app.dCBB.Text = 'Clustering basic benchmark';
            app.dYY = uimenu(app.load_more_data);
            app.dYY.MenuSelectedFcn = createCallbackFcn(app, @button_load_Callback, true);
            app.dYY.Text = 'Yan and Ye';
            app.dGap = uimenu(app.load_more_data);
            app.dGap.MenuSelectedFcn = createCallbackFcn(app, @button_load_Callback, true);
            app.dGap.Text = 'Gap';
            app.dMixed = uimenu(app.load_more_data);
            app.dMixed.MenuSelectedFcn = createCallbackFcn(app, @button_load_Callback, true);
            app.dMixed.Text = 'Mixed';
            app.GeneratedatasetMenu = uimenu(app.clustering_gui);
            app.GeneratedatasetMenu.Text = 'Generate dataset';
            app.dBrodinova = uimenu(app.GeneratedatasetMenu);
            app.dBrodinova.MenuSelectedFcn = createCallbackFcn(app, @dBrodinova_Callback, true);
            app.dBrodinova.Text = 'Brodinova et al.';
            app.panel_clustering = uipanel(app.clustering_gui);
            app.panel_clustering.Title = 'Clustering';
            app.text_norm = uilabel(app.panel_clustering);
            app.text_norm.Text = 'Normalization';
            app.init_norm = uidropdown(app.panel_clustering);
            app.init_norm.Items = {};
            app.init_norm.Value = {};
            app.text_centers = uilabel(app.panel_clustering);
            app.text_centers.Text = 'Centroids';
            app.init_centers = uidropdown(app.panel_clustering);
            app.init_centers.Items = {};
            app.init_centers.Value = {};
            app.text_clustering = uilabel(app.panel_clustering);
            app.text_clustering.Text = 'Clustering';
            app.init_clustering = uidropdown(app.panel_clustering);
            app.init_clustering.Items = {};
            app.init_clustering.ValueChangedFcn = createCallbackFcn(app, @init_clustering_Callback, true);
            app.init_clustering.Value = {};
            app.uipanel2 = uipanel(app.panel_clustering);
            app.uipanel2.Title = 'Test Properties';
            app.text_step_s = uilabel(app.uipanel2);
            app.text_step_s.Text = 'Step';
            app.test_end_s = uilabel(app.uipanel2);
            app.test_end_s.Text = 'End s';
            app.text_start_s = uilabel(app.uipanel2);
            app.text_start_s.Text = 'Start s';
            app.text_start_k = uilabel(app.uipanel2);
            app.text_start_k.Text = 'Start k';
            app.text_end_k = uilabel(app.uipanel2);
            app.text_end_k.Text = 'End k';
            app.text_step_k = uilabel(app.uipanel2);
            app.text_step_k.Text = 'Step';
            app.s_end = uidropdown(app.uipanel2);
            app.s_end.Items = {};
            app.s_end.Value = {};
            app.s_start = uidropdown(app.uipanel2);
            app.s_start.Items = {};
            app.s_start.Value = {};
            app.k_start = uidropdown(app.uipanel2);
            app.k_start.Items = {};
            app.k_start.Value = {};
            app.k_end = uidropdown(app.uipanel2);
            app.k_end.Items = {};
            app.k_end.Value = {};
            app.k_step = uidropdown(app.uipanel2);
            app.k_step.Items = {};
            app.k_step.Value = {};
            app.s_step = uidropdown(app.uipanel2);
            app.s_step.Items = {};
            app.s_step.Value = {};
            app.run_clustering = uibutton(app.panel_clustering, 'push');
            app.run_clustering.ButtonPushedFcn = createCallbackFcn(app, @run_clustering_Callback, true);
            app.run_clustering.Text = 'Run Clustering';
            app.dataset_feats = uitable(app.panel_clustering);
            app.dataset_feats.ColumnName = {'Feature'; 'Color'; 'Active'};
            app.dataset_feats.ColumnEditable = [false false true];
            app.save_clustering = uibutton(app.panel_clustering, 'push');
            app.save_clustering.ButtonPushedFcn = createCallbackFcn(app, @save_clustering_Callback, true);
            app.save_clustering.Text = 'Save';
            app.panel_data = uipanel(app.clustering_gui);
            app.panel_data.Title = 'Data';
            app.plotter = uiaxes(app.panel_data);
            app.plotDim1 = uidropdown(app.panel_data);
            app.plotDim1.Items = {'1'};
            app.plotDim1.ValueChangedFcn = createCallbackFcn(app, @plotter_exe, true);
            app.plotDim1.Value = '1';
            app.plotDim2 = uidropdown(app.panel_data);
            app.plotDim2.Items = {'2'};
            app.plotDim2.ValueChangedFcn = createCallbackFcn(app, @plotter_exe, true);
            app.plotDim2.Value = '2';
            app.plotDim3 = uidropdown(app.panel_data);
            app.plotDim3.Items = {'3'};
            app.plotDim3.ValueChangedFcn = createCallbackFcn(app, @plotter_exe, true);
            app.plotDim3.Value = '3';
            app.generate_new_figure = uicheckbox(app.panel_data);
            app.generate_new_figure.ValueChangedFcn = createCallbackFcn(app, @plotter_exe, true);
            app.generate_new_figure.Text = 'new figure';
            app.button_load = uibutton(app.panel_data, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @button_load_Callback, true);
            app.button_load.Text = 'Load Dataset';
            app.dataset_norm = uidropdown(app.panel_data);
            app.dataset_norm.Items = {};
            app.dataset_norm.ValueChangedFcn = createCallbackFcn(app, @plotter_exe, true);
            app.dataset_norm.Value = {};
            app.features_values = uidropdown(app.panel_data);
            app.features_values.Items = {};
            app.features_values.ValueChangedFcn = createCallbackFcn(app, @features_values_Callback, true);
            app.features_values.Value = {};
            app.hold_features_properties = uicheckbox(app.panel_data);
            app.hold_features_properties.Text = 'hold figure';
            app.text_generate_figure = uilabel(app.panel_data);
            app.text_generate_figure.Text = 'Generate figure';
            app.text_normalize_data = uilabel(app.panel_data);
            app.text_normalize_data.Text = 'Normalize data';
            app.text_dimensions = uilabel(app.panel_data);
            app.text_dimensions.Text = 'Dimensions';
            app.text_feature_values = uilabel(app.panel_data);
            app.text_feature_values.Text = 'Feature values';
            app.features_values_plot = uidropdown(app.panel_data);
            app.features_values_plot.Items = {};
            app.features_values_plot.Value = {};
            app.TextArea = uitextarea(app.panel_data);
            app.panel_results = uipanel(app.clustering_gui);
            app.panel_results.Title = 'Results';
            app.load_clustering = uibutton(app.panel_results, 'push');
            app.load_clustering.ButtonPushedFcn = createCallbackFcn(app, @load_clustering_Callback, true);
            app.load_clustering.Text = 'Load Clustering Results';
            app.results_pop = uidropdown(app.panel_results);
            app.results_pop.Items = {};
            app.results_pop.ValueChangedFcn = createCallbackFcn(app, @results_pop_Callback, true);
            app.results_pop.Value = {};
            % Show the figure after all components are created
            app.clustering_gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clustering_gui_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.clustering_gui)

            % Execute the startup function
            runStartupFcn(app, @clustering_gui_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.clustering_gui)
        end
    end
end